classdef nowa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ZadaniaTab                      matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Siglal                          matlab.ui.control.UIAxes
        Real                            matlab.ui.control.UIAxes
        Imag                            matlab.ui.control.UIAxes
        Amplitudowa                     matlab.ui.control.UIAxes
        Fazowa                          matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        PorwnaniewykreswTab             matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        zOknem                          matlab.ui.control.UIAxes
        bezOkna                         matlab.ui.control.UIAxes
        InformacjeoautorzeTab           matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        AutorMichaStanisawskiNumerindeksu253241Label  matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        SignaltypeDropDown              matlab.ui.control.DropDown
        SignaltypeDropDownLabel         matlab.ui.control.Label
        NSpinnerLabel                   matlab.ui.control.Label
        NSpinner                        matlab.ui.control.Spinner
        kSpinnerLabel                   matlab.ui.control.Label
        kSpinner                        matlab.ui.control.Spinner
        FpSpinnerLabel                  matlab.ui.control.Label
        FpSpinner                       matlab.ui.control.Spinner
        FazaSpinnerLabel                matlab.ui.control.Label
        FazaSpinner                     matlab.ui.control.Spinner
        ASpinnerLabel                   matlab.ui.control.Label
        ASpinner                        matlab.ui.control.Spinner
        GridLayout4                     matlab.ui.container.GridLayout
        TypeFormulaEditFieldLabel       matlab.ui.control.Label
        formulaField                    matlab.ui.control.EditField
        chboxcustomsignal               matlab.ui.control.CheckBox
        GridLayout5                     matlab.ui.container.GridLayout
        WielkointerpolacjiSpinnerLabel  matlab.ui.control.Label
        WielkointerpolacjiSpinner       matlab.ui.control.Spinner
        InterpolacjazeramiCheckBox      matlab.ui.control.CheckBox
        WczskallogarytmiczndlawidamaamplitudowegoCheckBox  matlab.ui.control.CheckBox
        WczoczasuwsekundachCheckBox     matlab.ui.control.CheckBox
        RodzajoknaDropDown              matlab.ui.control.DropDown
        RodzajoknaDropDownLabel         matlab.ui.control.Label
        ParametryoknaSpinnerLabel       matlab.ui.control.Label
        ParametryoknaSpinner            matlab.ui.control.Spinner
        AplikacjasucadoanalizyDFTsygnauLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        signew
        %sig % sgnal type
        fun %function to plot
        X
        A %A parameter
        k %k parameter
        N %N parameter
        phi %phi parameter
        Fp %Fp field
        y %signal
        n
        nInterpol % wielkość interpolacji zerami
        interpol_chbox%czy chbox od interpolacji jest kliknięty
        cust %bool od customowego sygnału
        osWykresu %do zmiany osi czasu wykresu
        logarytmiczna %do włączenia skali logarytmicznej
        typokna
        okno%jakie okno jest akurat ustawione
        parametryokna%parametry okna ze spinnera
        podstSyg%do zachowania podstawowego sygnału 
    end


    methods (Access = private)
       
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Metody updatujące parametry %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function [] = update_k(app, k)
            app.k=k;
        end
        
        function [] = update_N(app, N)
            app.N=N;
            app.nInterpol=2^nextpow2(N);
            app.WielkointerpolacjiSpinner.Value=app.nInterpol;
        end

        function [] = update_fun(app, fun)
            app.fun = eval(fun);
        end
        
        function [] = update_A(app,A)
            app.A=A;
        end
        
        function [] = update_phi(app,phi)
            app.phi=phi;
        end
        
        function [] = update_fp(app, Fp)
            app.Fp = Fp;
        end
        
        function [] = update_customSig(app, customSignal)
            app.y = evalin("base",customSignal);
            app.y=app.y';
            app.N=length(app.y);%długość sygnału
        end
        
        function [] = update_interpol(app,nInterpol)
            %ustawienie żeby interpolacja była potęgą 2
            app.nInterpol = 2^nextpow2(nInterpol);
            if(app.nInterpol < app.N)
                app.nInterpol = app.N;
            end
            app.WielkointerpolacjiSpinner.Value = app.nInterpol;
            
        end


        function [] = update_typokna(app,typokna)
            %jak chbox od interpolacji jest kliknięty to
            
            if (app.interpol_chbox==1)
                N=app.nInterpol;
            else
                N=app.N;
            end
            
            if (isequal(typokna, '@rectwin'))
                set(app.ParametryoknaSpinner, 'enable', 'off');
                set(app.ParametryoknaSpinnerLabel, 'enable', 'off');
                
                app.okno=ones(1,N);
            end
            if (isequal(typokna, '@triang') || isequal(typokna, '@hann') || isequal(typokna, '@hamming') || isequal(typokna, '@blackman'))
                set(app.ParametryoknaSpinner, 'enable', 'off');
                set(app.ParametryoknaSpinnerLabel, 'enable', 'off');
                                
                app.typokna=eval(typokna);
                app.okno=(app.typokna(N))';
                %app.okno=(app.typokna(N,app.parametryokna))';
            end
            
            if(isequal(typokna,'sinp'))
                set(app.ParametryoknaSpinner, 'enable', 'on');
                set(app.ParametryoknaSpinnerLabel, 'enable', 'on');
                
                app.okno=sin(N).^ app.parametryokna;
            end
            
            if(isequal(typokna,'@chebwin')||isequal(typokna,'@kaiser'))
                set(app.ParametryoknaSpinner, 'enable', 'on');
                set(app.ParametryoknaSpinnerLabel, 'enable', 'on');
                
                app.typokna=eval(typokna);
                app.okno=(app.typokna(N,app.parametryokna))';
            end
        end
        
        function [] = update_parametryokna(app,parametryokna)
            app.parametryokna=double(parametryokna);
        end
        
        function [] = refresh(app)
            %plot(app.Real,app.signew);
            %t = [0:0.1:2*pi];
            %a = sin(t);
            
            %n=0:1/app.Fp:(app.N-1);
            %app.fun=eval(app.sig);
            %y=app.A*app.fun(2*pi*app.k*n/app.N+app.phi);
            %plot(app.Siglal,app.fun(0:.1:100));
            %plot(app.Siglal,n,y)
            %app.X=real(fft(y)/app.N);
            %stem(app.DFT,n,app.X)
            
            
            %app.ff=fft(app.fun(0:.1:100));
            %plot(app.DFT,app.ff);


            %argument exp() to 1j
            arg_real = 1;%przeklejone z instrukcji Pana Magistra
            if(isequal(@exp,app.fun))
                arg_real = 1j;
            end
            %app.n=0:1/app.Fp:(app.N-1);
            %app.n = 0 :1/app.Fp: (app.N - 1);
            
           
            %możliwość wprowadzenia sygnału ręcznie 
            if (app.cust)
                app.update_customSig(app.formulaField.Value);
                %interpolacja zerami
                if(app.InterpolacjazeramiCheckBox.Value)
                    app.n=0:(app.nInterpol-1);
                    variable=app.nInterpol-app.N;
                    app.y=[app.y zeros(1,variable)];
                    disp('a');
                else
                    app.update_customSig(app.formulaField.Value);
                    app.n=0 : (app.N - 1);
                    disp('b');
                end
            else
                app.n=0 : (app.N - 1);
                app.y=app.A*app.fun(arg_real*2*pi*app.k*app.n/app.N+arg_real*pi*app.phi/180);%sygnal
                %disp(app.y);
                %disp(app.n);
                
                if(app.InterpolacjazeramiCheckBox.Value)
                    app.n=0:(app.nInterpol-1);
                    variable=app.nInterpol-app.N;
                    app.y=[app.y zeros(1,variable)];
                    %disp('c');
           
                end
                 
            end
            
            %nanoszenie okna
            update_typokna(app,app.RodzajoknaDropDown.Value);
            app.podstSyg=app.y;%zachowanie podstawowoego sygnału
            disp(app.podstSyg);
            app.y=app.y.*app.okno;
            %disp(app.okno)
            
            if(app.osWykresu)
                x=app.n.*(1/app.Fp);
            else
                x=app.n;
            end
            
            if(isequal(@exp,app.fun))
                plot(app.Siglal,app.y);%sygnal
                hold(app.Siglal,'on');
                plot(app.Siglal,app.okno,'r');%okno
                hold(app.Siglal,'off');
            else
                plot(app.Siglal,x,app.y); 
                hold(app.Siglal,'on');
                plot(app.Siglal,x,app.okno,'r');
                hold(app.Siglal,'off');
                %disp(app.y)
            end
            plot(app.UIAxes,app.y);%rysowanie sygbnału bez okna czasowego wykorzystane głównie przy wykresie exp, żeby sobaczyć więcej szczegółów


            ff=fft(app.y);%obliczanie dft 
            
            %ff2=(1/app.N)*sum(app.okno);
            %--------------------------------------------------------
            % %{
            if(app.logarytmiczna==1)
                re=20*log10(real(ff/app.N));%cz.rzeczywista
                im=20*log10(imag(ff/app.N));%cz.urojona
                
                absy=20*log10(abs(ff/app.N));
                angly=20*log10(angle(ff));
            else
                re=real(ff/app.N);%cz.rzeczywista
                im=imag(ff/app.N);%cz.urojona
                
                absy=abs(ff/app.N);
                angly=angle(ff);
            end
            % %}
            %---------------------------------------------------------
            %---------------------------------------------------------
            %{
            re=real(ff/app.N);%cz.rzeczywista
            im=imag(ff/app.N);%cz.urojona
            
            absy=abs(ff/app.N);
            angly=angle(ff);
            %}
            %---------------------------------------------------------
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            % WYKRES PLOTOWANY PLOTEM %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
             %{
            plot(app.Real,x, re);
            plot(app.Imag,x, im);
            plot(app.Amplitudowa,x, absy);
            plot(app.Fazowa,x, angly*100/pi);
             %}
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            % WYKRES PLOTOWANY STEMEM %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%
            % %{
            stem(app.Real,x, re);
            stem(app.Imag,x, im);
            stem(app.Amplitudowa,x, absy);
            stem(app.Fazowa,x, angly*100/pi);
            % %}
            
            %parametry do okienek z porównaniem
            plot(app.zOknem,x, angly*100/pi);%tu użyte są te same wartości bo wykres użyty bedzie do porówania
            ff2=fft(app.podstSyg);
            angly2=angle(ff2);
            plot(app.bezOkna,x, angly2*100/pi);
            
            
            %{
            if(app.logarytmiczna)
                loglog(app.Real,x, re);
                loglog(app.Imag,x, im);
                loglog(app.Amplitudowa,x, absy);
                loglog(app.Fazowa,x, angly*100/pi);
            else
                stem(app.Real,x, re);
                stem(app.Imag,x, im);
                stem(app.Amplitudowa,x, absy);
                stem(app.Fazowa,x, angly*100/pi);
            end
            %}
            
            %disp(app.interpol_chbox);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_A(app, app.ASpinner.Value);
            update_N(app, app.NSpinner.Value);
            update_phi(app, app.FazaSpinner.Value);
            update_k(app, app.kSpinner.Value);
            update_fp(app, app.FpSpinner.Value);
            update_fun(app, app.SignaltypeDropDown.Value);
            update_parametryokna(app, app.ParametryoknaSpinner.Value);
            update_typokna(app, app.RodzajoknaDropDown.Value);
            %update_interpol(app,app)
            refresh(app);
        end

        % Value changed function: formulaField
        function Formula(app, event)
            %str = app.formulaField.Value;
            %app.signew=evalin("base", str);
            update_customSig(app,app.formulaField.Value);
            
            refresh(app);
        end

        % Value changed function: SignaltypeDropDown
        function SignalType(app, event)
            %app.sig=app.SignaltypeDropDown.Value;  
            %disp(app.sig)
            update_fun(app,app.SignaltypeDropDown.Value);  
            refresh(app);
        end

        % Value changed function: ASpinner
        function AField(app, event)
            %app.A=app.ASpinner.Value;
            %disp(app.A)
            update_A(app,app.ASpinner.Value)
            refresh(app);
        end

        % Value changed function: FazaSpinner
        function FazaField(app, event)
            %app.phi=app.FazaSpinner.Value;
            update_phi(app, app.FazaSpinner.Value);
            refresh(app);
        end

        % Value changed function: NSpinner
        function NField(app, event)
            %app.N=app.NSpinner.Value;
            %disp(app.N)
            update_N(app,app.NSpinner.Value);
            refresh(app);            
        end

        % Value changed function: kSpinner
        function kfield(app, event)
            %%update_k(app, app.kSpinner.Value);
            %app.k=app.kSpinner.Value;
            
            %app.k=app.kSpinner.Value;
            %disp(app.k)
            update_k(app,app.kSpinner.Value);
            refresh(app);
        end

        % Value changed function: FpSpinner
        function FpField(app, event)
            app.Fp = app.FpSpinner.Value;
            refresh(app);
        end

        % Value changed function: chboxcustomsignal
        function customsignal(app, event)
            app.cust = app.chboxcustomsignal.Value;
            if(app.cust)
                %wyłączone
                set(app.ASpinner,'enable', 'off');
                set(app.NSpinner,'enable', 'off');
                set(app.ASpinnerLabel,'enable', 'off');
                set(app.NSpinnerLabel,'enable', 'off');
                set(app.FazaSpinner,'enable', 'off');
                set(app.FazaSpinnerLabel,'enable', 'off');
                set(app.kSpinner,'enable', 'off');
                set(app.kSpinnerLabel,'enable', 'off');
                set(app.FpSpinner,'enable', 'off');
                set(app.FpSpinnerLabel,'enable', 'off');
                set(app.SignaltypeDropDown,'enable', 'off');
                set(app.SignaltypeDropDownLabel,'enable', 'off');
                %włączone
                set(app.formulaField,'enable', 'on');
            else
                %włączone
                set(app.ASpinner,'enable', 'on');
                set(app.NSpinner,'enable', 'on');
                set(app.ASpinnerLabel,'enable', 'on');
                set(app.NSpinnerLabel,'enable', 'on');
                set(app.FazaSpinner,'enable', 'on');
                set(app.FazaSpinnerLabel,'enable', 'on');
                set(app.kSpinner,'enable', 'on');
                set(app.kSpinnerLabel,'enable', 'on');
                set(app.FpSpinner,'enable', 'on');
                set(app.FpSpinnerLabel,'enable', 'on');
                set(app.SignaltypeDropDown,'enable', 'on');
                set(app.SignaltypeDropDownLabel,'enable', 'on');
                %wyłączone
                set(app.formulaField,'enable', 'off');
            end
            refresh(app);
        end

        % Value changed function: InterpolacjazeramiCheckBox
        function Interpolacjazerami(app, event)
            app.interpol_chbox = app.InterpolacjazeramiCheckBox.Value;
            %update_N(app,app.WielkointerpolacjiSpinner.Value);
            if(app.interpol_chbox==1)
                set(app.WielkointerpolacjiSpinner, 'enable','on');
                set(app.WielkointerpolacjiSpinnerLabel, 'enable','on');
            else
                set(app.WielkointerpolacjiSpinner, 'enable','off');
                set(app.WielkointerpolacjiSpinnerLabel, 'enable','off');
            end
            %disp(app.interpol_chbox)
            refresh(app);
        end

        % Value changed function: WielkointerpolacjiSpinner
        function Wielkointerpolacji(app, event)
            value = app.WielkointerpolacjiSpinner.Value;
            update_interpol(app, app.WielkointerpolacjiSpinner.Value);
            refresh(app);
        end

        % Value changed function: WczoczasuwsekundachCheckBox
        function Wczoczasuwsekundach(app, event)
            app.osWykresu = app.WczoczasuwsekundachCheckBox.Value;
            if (app.osWykresu)
                set(app.FpSpinner, 'enable','on');
                set(app.FpSpinnerLabel, 'enable','on');
            else
                set(app.FpSpinner, 'enable','off');
                set(app.FpSpinnerLabel, 'enable','off');
            end
            refresh(app);
        end

        % Value changed function: 
        % WczskallogarytmiczndlawidamaamplitudowegoCheckBox
        function Wlaczskalelogarytmiczna(app, event)
            app.logarytmiczna = app.WczskallogarytmiczndlawidamaamplitudowegoCheckBox.Value;
            %disp(app.logarytmiczna)
            refresh(app);
        end

        % Value changed function: RodzajoknaDropDown
        function RodzajoknaDropDownValueChanged(app, event)
            value = app.RodzajoknaDropDown.Value;
            %disp(value)
            update_typokna(app, value);
            refresh(app);            
        end

        % Value changed function: ParametryoknaSpinner
        function Parametryokna(app, event)
            value = app.ParametryoknaSpinner.Value;
            update_parametryokna(app,value);
            update_typokna(app,app.RodzajoknaDropDown.Value);
            refresh(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ZadaniaTab = uitab(app.TabGroup);
            app.ZadaniaTab.Title = 'Zadania';
            app.GridLayout2 = uigridlayout(app.ZadaniaTab);
            app.Siglal = uiaxes(app.GridLayout2);
            title(app.Siglal, 'Sygnał')
            xlabel(app.Siglal, 'X')
            ylabel(app.Siglal, 'Y')
            zlabel(app.Siglal, 'Z')
            app.Real = uiaxes(app.GridLayout2);
            title(app.Real, 'Część rzeczywista')
            xlabel(app.Real, 'X')
            ylabel(app.Real, 'Y')
            zlabel(app.Real, 'Z')
            app.Imag = uiaxes(app.GridLayout2);
            title(app.Imag, 'Część urojona')
            xlabel(app.Imag, 'X')
            ylabel(app.Imag, 'Y')
            zlabel(app.Imag, 'Z')
            app.Amplitudowa = uiaxes(app.GridLayout2);
            title(app.Amplitudowa, 'Charakterystyka amplitudowa')
            xlabel(app.Amplitudowa, 'X')
            ylabel(app.Amplitudowa, 'Y')
            zlabel(app.Amplitudowa, 'Z')
            app.Fazowa = uiaxes(app.GridLayout2);
            title(app.Fazowa, 'Charakterystyka fazowa')
            xlabel(app.Fazowa, 'X')
            ylabel(app.Fazowa, 'Y')
            zlabel(app.Fazowa, 'Z')
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PorwnaniewykreswTab = uitab(app.TabGroup);
            app.PorwnaniewykreswTab.Title = 'Porównanie wykresów';
            app.GridLayout7 = uigridlayout(app.PorwnaniewykreswTab);
            app.zOknem = uiaxes(app.GridLayout7);
            title(app.zOknem, 'Charakterystyka amplitudowa z oknem ')
            xlabel(app.zOknem, 'X')
            ylabel(app.zOknem, 'Y')
            zlabel(app.zOknem, 'Z')
            app.bezOkna = uiaxes(app.GridLayout7);
            title(app.bezOkna, 'Charakterystyka amplitudowa bez oknem ')
            xlabel(app.bezOkna, 'X')
            ylabel(app.bezOkna, 'Y')
            zlabel(app.bezOkna, 'Z')
            app.InformacjeoautorzeTab = uitab(app.TabGroup);
            app.InformacjeoautorzeTab.Title = 'Informacje o autorze';
            app.GridLayout6 = uigridlayout(app.InformacjeoautorzeTab);
            app.AutorMichaStanisawskiNumerindeksu253241Label = uilabel(app.GridLayout6);
            app.AutorMichaStanisawskiNumerindeksu253241Label.Text = {'Autor:'; 'Michał Stanisławski'; 'Numer indeksu: 253241'};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SignaltypeDropDown = uidropdown(app.GridLayout3);
            app.SignaltypeDropDown.Items = {'sine', 'cosine', 'exp'};
            app.SignaltypeDropDown.ItemsData = {'@sin', '@cos', '@exp'};
            app.SignaltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalType, true);
            app.SignaltypeDropDown.Value = '@sin';
            app.SignaltypeDropDownLabel = uilabel(app.GridLayout3);
            app.SignaltypeDropDownLabel.Text = 'Signal type';
            app.NSpinnerLabel = uilabel(app.GridLayout3);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.GridLayout3);
            app.NSpinner.ValueChangedFcn = createCallbackFcn(app, @NField, true);
            app.NSpinner.Value = 64;
            app.kSpinnerLabel = uilabel(app.GridLayout3);
            app.kSpinnerLabel.Text = 'k';
            app.kSpinner = uispinner(app.GridLayout3);
            app.kSpinner.ValueChangedFcn = createCallbackFcn(app, @kfield, true);
            app.kSpinner.Value = 1;
            app.FpSpinnerLabel = uilabel(app.GridLayout3);
            app.FpSpinnerLabel.Enable = 'off';
            app.FpSpinnerLabel.Text = 'Fp';
            app.FpSpinner = uispinner(app.GridLayout3);
            app.FpSpinner.ValueChangedFcn = createCallbackFcn(app, @FpField, true);
            app.FpSpinner.Enable = 'off';
            app.FpSpinner.Value = 1000;
            app.FazaSpinnerLabel = uilabel(app.GridLayout3);
            app.FazaSpinnerLabel.Text = 'Faza';
            app.FazaSpinner = uispinner(app.GridLayout3);
            app.FazaSpinner.ValueChangedFcn = createCallbackFcn(app, @FazaField, true);
            app.ASpinnerLabel = uilabel(app.GridLayout3);
            app.ASpinnerLabel.Text = 'A';
            app.ASpinner = uispinner(app.GridLayout3);
            app.ASpinner.ValueChangedFcn = createCallbackFcn(app, @AField, true);
            app.ASpinner.Value = 1;
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.TypeFormulaEditFieldLabel = uilabel(app.GridLayout4);
            app.TypeFormulaEditFieldLabel.Text = 'Type Formula';
            app.formulaField = uieditfield(app.GridLayout4, 'text');
            app.formulaField.ValueChangedFcn = createCallbackFcn(app, @Formula, true);
            app.formulaField.Enable = 'off';
            app.formulaField.Value = 'sin(0:.1:10)';
            app.chboxcustomsignal = uicheckbox(app.GridLayout4);
            app.chboxcustomsignal.ValueChangedFcn = createCallbackFcn(app, @customsignal, true);
            app.chboxcustomsignal.Text = {'Zezwól na customowy '; 'sygnał'};
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.WielkointerpolacjiSpinnerLabel = uilabel(app.GridLayout5);
            app.WielkointerpolacjiSpinnerLabel.Enable = 'off';
            app.WielkointerpolacjiSpinnerLabel.Text = {'Wielkość '; 'interpolacji'};
            app.WielkointerpolacjiSpinner = uispinner(app.GridLayout5);
            app.WielkointerpolacjiSpinner.Limits = [0 Inf];
            app.WielkointerpolacjiSpinner.ValueChangedFcn = createCallbackFcn(app, @Wielkointerpolacji, true);
            app.WielkointerpolacjiSpinner.Enable = 'off';
            app.WielkointerpolacjiSpinner.Value = 64;
            app.InterpolacjazeramiCheckBox = uicheckbox(app.GridLayout5);
            app.InterpolacjazeramiCheckBox.ValueChangedFcn = createCallbackFcn(app, @Interpolacjazerami, true);
            app.InterpolacjazeramiCheckBox.Text = 'Interpolacja zerami';
            app.WczskallogarytmiczndlawidamaamplitudowegoCheckBox = uicheckbox(app.GridLayout5);
            app.WczskallogarytmiczndlawidamaamplitudowegoCheckBox.ValueChangedFcn = createCallbackFcn(app, @Wlaczskalelogarytmiczna, true);
            app.WczskallogarytmiczndlawidamaamplitudowegoCheckBox.Text = {'Włącz skalę '; 'logarytmiczną dla'; 'widama amplitudowego'};
            app.WczoczasuwsekundachCheckBox = uicheckbox(app.GridLayout5);
            app.WczoczasuwsekundachCheckBox.ValueChangedFcn = createCallbackFcn(app, @Wczoczasuwsekundach, true);
            app.WczoczasuwsekundachCheckBox.Text = {'Włącz oś czasu '; 'w sekundach '};
            app.RodzajoknaDropDown = uidropdown(app.GridLayout5);
            app.RodzajoknaDropDown.Items = {'Prostokatne', 'Bartleta', 'Hanna', 'Hamminga', 'Blackmana', 'Kaisera', 'Dolpha-Czebyszewa', 'sin^p'};
            app.RodzajoknaDropDown.ItemsData = {'@rectwin', '@triang', '@hann', '@hamming', '@blackman', '@kaiser', '@chebwin', 'sinp'};
            app.RodzajoknaDropDown.ValueChangedFcn = createCallbackFcn(app, @RodzajoknaDropDownValueChanged, true);
            app.RodzajoknaDropDown.Value = '@rectwin';
            app.RodzajoknaDropDownLabel = uilabel(app.GridLayout5);
            app.RodzajoknaDropDownLabel.Text = 'Rodzaj okna';
            app.ParametryoknaSpinnerLabel = uilabel(app.GridLayout5);
            app.ParametryoknaSpinnerLabel.Text = {'Parametry'; 'okna'};
            app.ParametryoknaSpinner = uispinner(app.GridLayout5);
            app.ParametryoknaSpinner.ValueChangedFcn = createCallbackFcn(app, @Parametryokna, true);
            app.ParametryoknaSpinner.Value = 1;
            app.AplikacjasucadoanalizyDFTsygnauLabel = uilabel(app.GridLayout);
            app.AplikacjasucadoanalizyDFTsygnauLabel.Text = {'Aplikacja '; 'służąca do '; 'analizy DFT '; 'sygnału'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nowa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end